classdef EXnet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulateAdjacencyMatrixTab      matlab.ui.container.Tab
        ResultsPanel_2                  matlab.ui.container.Panel
        ExportFigureButton_2            matlab.ui.control.Button
        NetworkpvaluesEditField_2       matlab.ui.control.EditField
        NetworkpvaluesEditField_2Label  matlab.ui.control.Label
        SubnetworkSizesEditField_4      matlab.ui.control.EditField
        SubnetworkSizesEditField_4Label  matlab.ui.control.Label
        ExportButton_2                  matlab.ui.control.Button
        NumberofsubnetworksEditField_2  matlab.ui.control.NumericEditField
        NumberofsubnetworksEditField_2Label  matlab.ui.control.Label
        ReorderedAdjacencyMatrixPanel_2  matlab.ui.container.Panel
        Reordered_Matrix_2              matlab.ui.control.UIAxes
        ObservedAdjacencyMatrixPanel_2  matlab.ui.container.Panel
        StatisticalTestPanel_2          matlab.ui.container.Panel
        MEditField_2                    matlab.ui.control.NumericEditField
        MEditField_2Label               matlab.ui.control.Label
        AlgorithmDropDown_3             matlab.ui.control.DropDown
        AlgorithmDropDown_3Label        matlab.ui.control.Label
        alphaEditField_3                matlab.ui.control.NumericEditField
        alphaEditField_3Label           matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        Observed_Matrix_3               matlab.ui.control.UIAxes
        Observed_Matrix_2               matlab.ui.control.UIAxes
        FrompredefinedparametersPanel   matlab.ui.container.Panel
        CovariatesEditField             matlab.ui.control.NumericEditField
        CovariatesLabel                 matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        rho1EditField                   matlab.ui.control.EditField
        rho1EditFieldLabel              matlab.ui.control.Label
        VcEditField                     matlab.ui.control.EditField
        VcEditFieldLabel                matlab.ui.control.Label
        VariationEditField              matlab.ui.control.NumericEditField
        VariationEditFieldLabel         matlab.ui.control.Label
        EffectSizeEditField             matlab.ui.control.NumericEditField
        EffectSizeEditFieldLabel        matlab.ui.control.Label
        SampleSizeEditField             matlab.ui.control.NumericEditField
        SampleSizeEditFieldLabel        matlab.ui.control.Label
        rho0EditField                   matlab.ui.control.NumericEditField
        rho0EditFieldLabel              matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        SampleSizeandEffectSizeLabel    matlab.ui.control.Label
        GraphStructureLabel             matlab.ui.control.Label
        ImportAdjacencymatrixTab        matlab.ui.container.Tab
        StatisticalTestPanel            matlab.ui.container.Panel
        MEditField                      matlab.ui.control.NumericEditField
        MEditFieldLabel                 matlab.ui.control.Label
        AlgorithmDropDown_2             matlab.ui.control.DropDown
        AlgorithmDropDown_2Label        matlab.ui.control.Label
        alphaEditField_2                matlab.ui.control.NumericEditField
        alphaEditField_2Label           matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        ExportFigureButton              matlab.ui.control.Button
        NetworkpvaluesEditField         matlab.ui.control.EditField
        NetworkpvaluesEditFieldLabel    matlab.ui.control.Label
        SubnetworkSizesEditField_3      matlab.ui.control.EditField
        SubnetworkSizesEditField_3Label  matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        NumberofsubnetworksEditField    matlab.ui.control.NumericEditField
        NumberofsubnetworksEditFieldLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ReorderedAdjacencyMatrixPanel   matlab.ui.container.Panel
        Reordered_Matrix                matlab.ui.control.UIAxes
        ObservedAdjacencyMatrixPanel    matlab.ui.container.Panel
        Observed_Matrix                 matlab.ui.control.UIAxes
        FromexistingadjacencymatrixtxtcsvPanel  matlab.ui.container.Panel
        ImportButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        W1 = [];
        Wp = []; % Description
        Clist_dense = [];
        CID_dense = [];
        p_vec = [];
        W_dense = [];
        TableWp = [];
        TableClist_dense = [];
        TableW_dense = [];
        Tablep_vec = [];
        TableCID_dense = [];


    end
    methods (Access = private)

        % Create UIFigure and components

        function modify_app(app)
            % Create Observed_Matrix
            app.Observed_Matrix.XTickLabel = [];
            app.Observed_Matrix.YTickLabel = [];
            xlabel(app.Observed_Matrix, 'Brain Region')
            ylabel(app.Observed_Matrix, 'Brain Region')
            title(app.Observed_Matrix, '')


            % create Reordered Matrix
            app.Reordered_Matrix.XTickLabel = [];
            app.Reordered_Matrix.YTickLabel = [];
            xlabel(app.Reordered_Matrix, 'Brain Region')
            ylabel(app.Reordered_Matrix, 'Brain Region')
            title(app.Reordered_Matrix, '')

            % Create Observed_Matrix
            app.Observed_Matrix_2.XTickLabel = [];
            app.Observed_Matrix_2.YTickLabel = [];
            xlabel(app.Observed_Matrix_2, 'Brain Region')
            ylabel(app.Observed_Matrix_2, 'Brain Region')
            title(app.Observed_Matrix_2, '')
            
            app.Observed_Matrix_3.XTickLabel = [];
            app.Observed_Matrix_3.YTickLabel = [];
            xlabel(app.Observed_Matrix_3, 'Brain Region')
            ylabel(app.Observed_Matrix_3, 'Brain Region')
            title(app.Observed_Matrix_3, '')

            % create Reordered Matrix
            app.Reordered_Matrix_2.XTickLabel = [];
            app.Reordered_Matrix_2.YTickLabel = [];
            xlabel(app.Reordered_Matrix_2, 'Brain Region')
            ylabel(app.Reordered_Matrix_2, 'Brain Region')
            title(app.Reordered_Matrix_2, '')

            % Set the text of app.SigmaEditFieldLabel to the Greek letter Sigma (Σ)
            app.VariationEditFieldLabel.Text = char(963);

            % Set the text of app.Rho1EditField_3Label to the Greek letter Rho (ρ) followed by '1'
            app.rho1EditFieldLabel.Text = [char(961),'1'];

            % Set the text of app.Rho0EditField_3Label to the Greek letter Rho (ρ) followed by '0'
            app.rho0EditFieldLabel.Text = [char(961),'0'];

            % Set the text of app.alphaEditFieldLabel to the Greek letter Alpha (α)
            app.alphaEditField_3Label.Text = char(945);

            % Set the text of app.alphaEditField_2Label to the Greek letter Alpha (α)
            app.alphaEditField_2Label.Text = char(945);

            % Disable the Editable property of the PowerCIEditField_4 to prevent user input
            app.NumberofsubnetworksEditField.Editable = 'off';
            % Disable the Editable property of the TimeelapsedsEditField to prevent user input
            app.SubnetworkSizesEditField_3.Editable = 'off';
            % Disable the Editable property of the PowerCIEditField_5 to prevent user input
            app.NetworkpvaluesEditField.Editable = 'off';

            % Disable the Editable property of the PowerCIEditField_4 to prevent user input
            app.NumberofsubnetworksEditField_2.Editable = 'off';
            % Disable the Editable property of the TimeelapsedsEditField to prevent user input
            app.SubnetworkSizesEditField_4.Editable = 'off';
            % Disable the Editable property of the PowerCIEditField_5 to prevent user input
            app.NetworkpvaluesEditField_2.Editable = 'off';


            % % Disable the Editable property of the TimeelapsedsEditField_2 to prevent user input
            % app.TimeelapsedsEditField_2.Editable = 'off';

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add utils to path
            utilsPath = [pwd, '\utils'];
            addpath(utilsPath);
            
            N = app.NEditField.Value;
            app.NumberofsubnetworksEditField.Editable = 'off';
            app.SubnetworkSizesEditField_3.Editable = 'off';
            modify_app(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path, indx] = uigetfile({'*.txt';'*.csv'});
            filename = [path file];
            app.Wp = importdata(filename);
            %             app.Observed_Matrix = Wp;
            imagesc(app.Observed_Matrix, app.Wp);colorbar(app.Observed_Matrix);colormap(app.Observed_Matrix,"jet")

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            fig = uifigure;
            d= uiprogressdlg(fig,...
                'Message','1','Cancelable','on','Indeterminate','on');
            drawnow

            % extract subnetworks
            if strcmp(app.AlgorithmDropDown_2.Value,'greedy')
                method = 'greedy';
                d.Message = sprintf('Searching for optimized parameters');
                [lambda_out, cut_out] = param_tuning(app.Wp, method);
                d.Message = sprintf('Extracting subnetworks');
                [app.W_dense, app.Clist_dense, app.CID_dense] = greedy_final(app.Wp, cut_out, lambda_out);
                %             elseif strcmp(app.AlgorithmDropDown_2.Value, 'spectral')
                %                 method = 'SICERS';
                %                 d.Message = sprintf('Searching for optimized parameters');
                %                 [lambda_out, cut_out] = param_tuning(app.Wp, method);
                %                 d.Message = sprintf('Extracting subnetworks');
                %                 [CID_dense,W_dense, Clist_dense]=SICERS_final(app.Wp,cut_out,lambda_out, 20);
            end

            %             d.Message = sprintf('Perform permutation test');
            % Calculate test statistics for each subnetwork
            T0 = test_statistics(app.W_dense, app.CID_dense, cut_out);
            % app.p_vec = T0;
            % perform permutation test
            W_vec = squareform(app.Wp);
            M_perm = app.MEditField.Value;

            T_vec = zeros(M_perm,1);
            d.Message = sprintf('Performing permutation test');
            for i = 1:M_perm
                % shuffle matrix Wp
                shuffled_order = randperm(length(W_vec));
                W_shuffled = squareform(W_vec(shuffled_order));
                % extract subnetworks
                [W_dense_temp, ~, CID_dense_temp] = greedy_final(W_shuffled, cut_out, lambda_out);
                T_temp = test_statistics(W_dense_temp, CID_dense_temp, cut_out);
                T_vec(i) = min(T_temp);
            end

            app.p_vec = zeros(length(app.CID_dense),1);
            for i = 1:length(app.CID_dense)
                app.p_vec(i) = sum(T0(i) > T_vec)/M_perm;
            end

            [subnet_idx] = app.p_vec < app.alphaEditField_2.Value;
            app.NumberofsubnetworksEditField.Value = sum(subnet_idx);
            [~,subnet_pos] = find(subnet_idx);

            % display
            % Initialize an empty string
            text = '[';
            text_pval = '[';
            % Iterate through the vector elements
            for i = 1:length(app.CID_dense(subnet_pos))
                % Append the current element to the text
                text = [text, num2str(app.CID_dense(i))];
                str_pval = sprintf('%.1e', app.p_vec(i));
                text_pval = [text_pval, str_pval];
                % Add a comma and space if it's not the last element
                if i < length(app.CID_dense(subnet_pos))
                    text = [text, ', '];
                    text_pval = [text_pval, ','];
                end
            end

            % Close the square bracket
            text = [text, ']'];
            text_pval = [text_pval, ']'];

            app.SubnetworkSizesEditField_3.Value = num2str(text);
            app.NetworkpvaluesEditField.Value = num2str(text_pval);


            % Close the dialog box
            close(d)
            close(fig)

            imagesc(app.Reordered_Matrix, app.W_dense);colorbar(app.Reordered_Matrix);colormap(app.Reordered_Matrix, "jet")

            hold(app.Reordered_Matrix, "on")

            for i = 1:sum(subnet_pos)
                if i == 1
                    starting_position = [1 1];
                else
                    starting_position = [1+app.CID_dense(i-1) 1+app.CID_dense(i-1)];
                end
                sz = [app.CID_dense(i) app.CID_dense(i)];
                rectangle(app.Reordered_Matrix, 'Position', [starting_position, sz], 'EdgeColor', 'r', 'LineWidth', 3);

            end
            hold(app.Reordered_Matrix, "off")

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Convert vectors and matrix to tables
            app.TableWp = array2table(app.Wp);

            app.TableClist_dense = array2table(app.Clist_dense);

            app.TableW_dense = array2table(app.W_dense);

            app.Tablep_vec = array2table(app.p_vec);

            app.TableCID_dense = array2table(app.CID_dense);

            % Choose the Excel file location and name
            [filename, pathname] = uiputfile('*.xlsx', 'Save as Excel File');

            % Check if the user didn't cancel the file dialog
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User selected Cancel')
                return;
            end

            fullpath = fullfile(pathname, filename);

            % Write each table to a different sheet
            writetable(app.TableWp, fullpath, 'Sheet', 'Wp');
            writetable(app.TableW_dense, fullpath, 'Sheet', 'W_dense');
            writetable(app.TableClist_dense, fullpath, 'Sheet', 'Clist');
            writetable(app.TableCID_dense, fullpath, 'Sheet', 'CID');
            writetable(app.Tablep_vec, fullpath, 'Sheet', 'p values');
        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            % Choose the location and format for saving the figure
            [filename, pathname, filterindex] = uiputfile({...
                '*.fig', 'MATLAB Figure (*.fig)'; ...
                '*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'; ...
                '*.tif', 'TIFF Image (*.tif)'; ...
                '*.pdf', 'PDF (*.pdf)'}, ...
                'Save Figure As');

            % Check if the user didn't cancel the file dialog
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User selected Cancel')
                return;
            end

            fullpath = fullfile(pathname, filename);

            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            imagesc(app.W_dense);colorbar;colormap("jet");
            % Save as png and fig files.
            saveas(fig, fullpath);
            delete(fig);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % fetch parameters of interest
            % Retrieve input values from app components
            subject_num = app.SampleSizeEditField.Value;            % Number of subjects
            sigma0 = app.VariationEditField.Value;          % SD for FC matrices between edges
            N = app.NEditField.Value;                  % Size of the entire network
            f2 = app.EffectSizeEditField.Value;            % Cohen's f2 for covariate-related edges
            cluster_size = str2num(app.VcEditField.Value);      % Size of the cluster
            rho_in = str2num(app.rho1EditField.Value);          % Proportion of significant edges within the covariate-related subnetwork
            rho_out = app.rho0EditField.Value;         % Proportion of significant edges outside the covariate-related subnetwork
            DF = subject_num - 2 - app.CovariatesEditField.Value;                  % Degrees of freedom
            % Call the 'sampling_ind_reg' function to obtain example networks
            [app.W1, app.Wp, ~, ~, ~, ~] = sampling_ind_reg(subject_num, N, sigma0, f2, DF, cluster_size, rho_in, rho_out);
            imagesc(app.Observed_Matrix_2, app.W1);
            colorbar(app.Observed_Matrix_2);
            colormap(app.Observed_Matrix_2, "jet");
            hold(app.Observed_Matrix_2, "on")

            for i = 1:length(cluster_size)
                if i == 1
                    starting_position = [1 1];
                else
                    starting_position = [1+cluster_size(i-1) 1+cluster_size(i-1)];
                end
                sz = [cluster_size(i) cluster_size(i)];
                rectangle(app.Observed_Matrix_2, 'Position', [starting_position, sz], 'EdgeColor', 'r', 'LineWidth', 3);

            end
            hold(app.Observed_Matrix_2, "off")


            imagesc(app.Observed_Matrix_3, app.Wp);colorbar(app.Observed_Matrix_3);colormap(app.Observed_Matrix_3,"jet");
            colorbar(app.Observed_Matrix_3);
            colormap(app.Observed_Matrix_3, "jet");
          
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            fig = uifigure;
            d= uiprogressdlg(fig,...
                'Message','1','Cancelable','on','Indeterminate','on');
            drawnow

            % extract subnetworks
            if strcmp(app.AlgorithmDropDown_3.Value,'greedy')
                method = 'greedy';
                d.Message = sprintf('Searching for optimized parameters');
                [lambda_out, cut_out] = param_tuning(app.Wp, method);
                d.Message = sprintf('Extracting subnetworks');
                [app.W_dense, app.Clist_dense, app.CID_dense] = greedy_final(app.Wp, cut_out, lambda_out);
                %             elseif strcmp(app.AlgorithmDropDown_2.Value, 'spectral')
                %                 method = 'SICERS';
                %                 d.Message = sprintf('Searching for optimized parameters');
                %                 [lambda_out, cut_out] = param_tuning(app.Wp, method);
                %                 d.Message = sprintf('Extracting subnetworks');
                %                 [CID_dense,W_dense, Clist_dense]=SICERS_final(app.Wp,cut_out,lambda_out, 20);
            end

            % Calculate test statistics for each subnetwork
            T0 = test_statistics(app.W_dense, app.CID_dense, cut_out);
            % app.p_vec = T0;
            % perform permutation test
            W_vec = squareform(app.Wp);
            M_perm = app.MEditField_2.Value;

            T_vec = zeros(M_perm,1);
            d.Message = sprintf('Performing permutation test');
            for i = 1:M_perm
                % shuffle matrix Wp
                shuffled_order = randperm(length(W_vec));
                W_shuffled = squareform(W_vec(shuffled_order));
                % extract subnetworks
                [W_dense_temp, ~, CID_dense_temp] = greedy_final(W_shuffled, cut_out, lambda_out);
                T_temp = test_statistics(W_dense_temp, CID_dense_temp, cut_out);
                T_vec(i) = min(T_temp);
            end

            app.p_vec = zeros(length(app.CID_dense),1);
            for i = 1:length(app.CID_dense)
                app.p_vec(i) = sum(T0(i) > T_vec)/M_perm;
            end

            [subnet_idx] = app.p_vec < app.alphaEditField_3.Value;
            app.NumberofsubnetworksEditField_2.Value = sum(subnet_idx);
            [~,subnet_pos] = find(subnet_idx);

            % display
            % Initialize an empty string
            text = '[';
            text_pval = '[';
            % Iterate through the vector elements
            for i = 1:length(app.CID_dense(subnet_pos))
                % Append the current element to the text
                text = [text, num2str(app.CID_dense(i))];
                str_pval = sprintf('%.1e', app.p_vec(i));
                text_pval = [text_pval, str_pval];
                % Add a comma and space if it's not the last element
                if i < length(app.CID_dense(subnet_pos))
                    text = [text, ', '];
                    text_pval = [text_pval, ','];
                end
            end

            % Close the square bracket
            text = [text, ']'];
            text_pval = [text_pval, ']'];

            app.SubnetworkSizesEditField_4.Value = num2str(text);
            app.NetworkpvaluesEditField_2.Value = num2str(text_pval);


            % Close the dialog box
            close(d)
            close(fig)

            imagesc(app.Reordered_Matrix_2, app.W_dense);colorbar(app.Reordered_Matrix_2);colormap(app.Reordered_Matrix_2, "jet")

            hold(app.Reordered_Matrix_2, "on")

            for i = 1:sum(subnet_pos)
                if i == 1
                    starting_position = [1 1];
                else
                    starting_position = [1+app.CID_dense(i-1) 1+app.CID_dense(i-1)];
                end
                sz = [app.CID_dense(i) app.CID_dense(i)];
                rectangle(app.Reordered_Matrix_2, 'Position', [starting_position, sz], 'EdgeColor', 'r', 'LineWidth', 3);

            end
            hold(app.Reordered_Matrix_2, "off")
            
            
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            % Convert vectors and matrix to tables
            app.TableWp = array2table(app.Wp);

            app.TableClist_dense = array2table(app.Clist_dense);

            app.TableW_dense = array2table(app.W_dense);

            app.Tablep_vec = array2table(app.p_vec);

            app.TableCID_dense = array2table(app.CID_dense);

            % Choose the Excel file location and name
            [filename, pathname] = uiputfile('*.xlsx', 'Save as Excel File');

            % Check if the user didn't cancel the file dialog
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User selected Cancel')
                return;
            end

            fullpath = fullfile(pathname, filename);

            % Write each table to a different sheet
            writetable(app.TableWp, fullpath, 'Sheet', 'Wp');
            writetable(app.TableW_dense, fullpath, 'Sheet', 'W_dense');
            writetable(app.TableClist_dense, fullpath, 'Sheet', 'Clist');
            writetable(app.TableCID_dense, fullpath, 'Sheet', 'CID');
            writetable(app.Tablep_vec, fullpath, 'Sheet', 'p values');
        end

        % Button pushed function: ExportFigureButton_2
        function ExportFigureButton_2Pushed(app, event)
            % Choose the location and format for saving the figure
            [filename, pathname, filterindex] = uiputfile({...
                '*.fig', 'MATLAB Figure (*.fig)'; ...
                '*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'; ...
                '*.tif', 'TIFF Image (*.tif)'; ...
                '*.pdf', 'PDF (*.pdf)'}, ...
                'Save Figure As');

            % Check if the user didn't cancel the file dialog
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User selected Cancel')
                return;
            end

            fullpath = fullfile(pathname, filename);

            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            imagesc(app.W_dense);colorbar;colormap("jet");
            % Save as png and fig files.
            saveas(fig, fullpath);
            delete(fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulateAdjacencyMatrixTab = uitab(app.TabGroup);
            app.SimulateAdjacencyMatrixTab.Title = 'Simulate Adjacency Matrix';
            app.FrompredefinedparametersPanel = uipanel(app.SimulateAdjacencyMatrixTab);
            app.FrompredefinedparametersPanel.Title = 'From pre-defined parameters';
            app.GraphStructureLabel = uilabel(app.FrompredefinedparametersPanel);
            app.GraphStructureLabel.Text = 'Graph Structure';
            app.SampleSizeandEffectSizeLabel = uilabel(app.FrompredefinedparametersPanel);
            app.SampleSizeandEffectSizeLabel.Text = 'Sample Size and Effect Size';
            app.NEditFieldLabel = uilabel(app.FrompredefinedparametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.FrompredefinedparametersPanel, 'numeric');
            app.NEditField.Value = 100;
            app.rho0EditFieldLabel = uilabel(app.FrompredefinedparametersPanel);
            app.rho0EditFieldLabel.Text = 'rho0';
            app.rho0EditField = uieditfield(app.FrompredefinedparametersPanel, 'numeric');
            app.rho0EditField.Value = 0.02;
            app.SampleSizeEditFieldLabel = uilabel(app.FrompredefinedparametersPanel);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.FrompredefinedparametersPanel, 'numeric');
            app.SampleSizeEditField.Value = 100;
            app.EffectSizeEditFieldLabel = uilabel(app.FrompredefinedparametersPanel);
            app.EffectSizeEditFieldLabel.Text = 'Effect Size';
            app.EffectSizeEditField = uieditfield(app.FrompredefinedparametersPanel, 'numeric');
            app.EffectSizeEditField.Value = 0.3;
            app.VariationEditFieldLabel = uilabel(app.FrompredefinedparametersPanel);
            app.VariationEditFieldLabel.Text = 'Variation';
            app.VariationEditField = uieditfield(app.FrompredefinedparametersPanel, 'numeric');
            app.VariationEditField.Value = 0.3;
            app.VcEditFieldLabel = uilabel(app.FrompredefinedparametersPanel);
            app.VcEditFieldLabel.Text = '|Vc|';
            app.VcEditField = uieditfield(app.FrompredefinedparametersPanel, 'text');
            app.VcEditField.Value = '[20, 10]';
            app.rho1EditFieldLabel = uilabel(app.FrompredefinedparametersPanel);
            app.rho1EditFieldLabel.Text = 'rho1';
            app.rho1EditField = uieditfield(app.FrompredefinedparametersPanel, 'text');
            app.rho1EditField.Value = '[0.45, 0.55]';
            app.GenerateButton = uibutton(app.FrompredefinedparametersPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.CovariatesLabel = uilabel(app.FrompredefinedparametersPanel);
            app.CovariatesLabel.Text = 'Covariates';
            app.CovariatesEditField = uieditfield(app.FrompredefinedparametersPanel, 'numeric');
            app.ObservedAdjacencyMatrixPanel_2 = uipanel(app.SimulateAdjacencyMatrixTab);
            app.ObservedAdjacencyMatrixPanel_2.Title = 'Observed Adjacency Matrix';
            app.Observed_Matrix_2 = uiaxes(app.ObservedAdjacencyMatrixPanel_2);
            app.Observed_Matrix_3 = uiaxes(app.ObservedAdjacencyMatrixPanel_2);
            app.RunButton_2 = uibutton(app.ObservedAdjacencyMatrixPanel_2, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.StatisticalTestPanel_2 = uipanel(app.ObservedAdjacencyMatrixPanel_2);
            app.StatisticalTestPanel_2.Title = 'Statistical Test';
            app.alphaEditField_3Label = uilabel(app.StatisticalTestPanel_2);
            app.alphaEditField_3Label.Text = 'alpha';
            app.alphaEditField_3 = uieditfield(app.StatisticalTestPanel_2, 'numeric');
            app.alphaEditField_3.Value = 0.05;
            app.AlgorithmDropDown_3Label = uilabel(app.StatisticalTestPanel_2);
            app.AlgorithmDropDown_3Label.Text = 'Algorithm';
            app.AlgorithmDropDown_3 = uidropdown(app.StatisticalTestPanel_2);
            app.AlgorithmDropDown_3.Items = {'greedy'};
            app.AlgorithmDropDown_3.Value = 'greedy';
            app.MEditField_2Label = uilabel(app.StatisticalTestPanel_2);
            app.MEditField_2Label.Text = 'M';
            app.MEditField_2 = uieditfield(app.StatisticalTestPanel_2, 'numeric');
            app.MEditField_2.Value = 100;
            app.ReorderedAdjacencyMatrixPanel_2 = uipanel(app.SimulateAdjacencyMatrixTab);
            app.ReorderedAdjacencyMatrixPanel_2.Title = 'Reordered Adjacency Matrix';
            app.Reordered_Matrix_2 = uiaxes(app.ReorderedAdjacencyMatrixPanel_2);
            title(app.Reordered_Matrix_2, 'Title')
            xlabel(app.Reordered_Matrix_2, 'X')
            ylabel(app.Reordered_Matrix_2, 'Y')
            app.ResultsPanel_2 = uipanel(app.SimulateAdjacencyMatrixTab);
            app.ResultsPanel_2.Title = 'Results';
            app.NumberofsubnetworksEditField_2Label = uilabel(app.ResultsPanel_2);
            app.NumberofsubnetworksEditField_2Label.Text = 'Number of subnetworks';
            app.NumberofsubnetworksEditField_2 = uieditfield(app.ResultsPanel_2, 'numeric');
            app.ExportButton_2 = uibutton(app.ResultsPanel_2, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.SubnetworkSizesEditField_4Label = uilabel(app.ResultsPanel_2);
            app.SubnetworkSizesEditField_4Label.Text = 'Subnetwork Sizes';
            app.SubnetworkSizesEditField_4 = uieditfield(app.ResultsPanel_2, 'text');
            app.NetworkpvaluesEditField_2Label = uilabel(app.ResultsPanel_2);
            app.NetworkpvaluesEditField_2Label.Text = 'Network p values';
            app.NetworkpvaluesEditField_2 = uieditfield(app.ResultsPanel_2, 'text');
            app.ExportFigureButton_2 = uibutton(app.ResultsPanel_2, 'push');
            app.ExportFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButton_2Pushed, true);
            app.ExportFigureButton_2.Text = 'Export Figure';
            app.ImportAdjacencymatrixTab = uitab(app.TabGroup);
            app.ImportAdjacencymatrixTab.Title = 'Import Adjacency matrix';
            app.FromexistingadjacencymatrixtxtcsvPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.FromexistingadjacencymatrixtxtcsvPanel.Title = 'From existing adjacency matrix (.txt, .csv)';
            app.ImportButton = uibutton(app.FromexistingadjacencymatrixtxtcsvPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ObservedAdjacencyMatrixPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.ObservedAdjacencyMatrixPanel.Title = 'Observed Adjacency Matrix';
            app.Observed_Matrix = uiaxes(app.ObservedAdjacencyMatrixPanel);
            title(app.Observed_Matrix, 'Title')
            xlabel(app.Observed_Matrix, 'X')
            ylabel(app.Observed_Matrix, 'Y')
            app.Observed_Matrix.Visible = 'off';
            app.ReorderedAdjacencyMatrixPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.ReorderedAdjacencyMatrixPanel.Title = 'Reordered Adjacency Matrix';
            app.Reordered_Matrix = uiaxes(app.ReorderedAdjacencyMatrixPanel);
            title(app.Reordered_Matrix, 'Title')
            xlabel(app.Reordered_Matrix, 'X')
            ylabel(app.Reordered_Matrix, 'Y')
            app.Reordered_Matrix.Visible = 'off';
            app.RunButton = uibutton(app.ImportAdjacencymatrixTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultsPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.ResultsPanel.Title = 'Results';
            app.NumberofsubnetworksEditFieldLabel = uilabel(app.ResultsPanel);
            app.NumberofsubnetworksEditFieldLabel.Text = 'Number of subnetworks';
            app.NumberofsubnetworksEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ExportButton = uibutton(app.ResultsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SubnetworkSizesEditField_3Label = uilabel(app.ResultsPanel);
            app.SubnetworkSizesEditField_3Label.Text = 'Subnetwork Sizes';
            app.SubnetworkSizesEditField_3 = uieditfield(app.ResultsPanel, 'text');
            app.NetworkpvaluesEditFieldLabel = uilabel(app.ResultsPanel);
            app.NetworkpvaluesEditFieldLabel.Text = 'Network p values';
            app.NetworkpvaluesEditField = uieditfield(app.ResultsPanel, 'text');
            app.ExportFigureButton = uibutton(app.ResultsPanel, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.StatisticalTestPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.StatisticalTestPanel.Title = 'Statistical Test';
            app.alphaEditField_2Label = uilabel(app.StatisticalTestPanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.alphaEditField_2 = uieditfield(app.StatisticalTestPanel, 'numeric');
            app.alphaEditField_2.Value = 0.05;
            app.AlgorithmDropDown_2Label = uilabel(app.StatisticalTestPanel);
            app.AlgorithmDropDown_2Label.Text = 'Algorithm';
            app.AlgorithmDropDown_2 = uidropdown(app.StatisticalTestPanel);
            app.AlgorithmDropDown_2.Items = {'greedy'};
            app.AlgorithmDropDown_2.Value = 'greedy';
            app.MEditFieldLabel = uilabel(app.StatisticalTestPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.StatisticalTestPanel, 'numeric');
            app.MEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EXnet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end