classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImportAdjacencymatrixTab       matlab.ui.container.Tab
        FromexistingadjacencymatrixmattxtcsvPanel  matlab.ui.container.Panel
        ImportButton                   matlab.ui.control.Button
        ObservedAdjacencyMatrixPanel   matlab.ui.container.Panel
        Observed_Matrix                matlab.ui.control.UIAxes
        ReorderedAdjacencyMatrixPanel  matlab.ui.container.Panel
        Reordered_Matrix               matlab.ui.control.UIAxes
        RunButton                      matlab.ui.control.Button
        ResultsPanel                   matlab.ui.container.Panel
        NumberofsubnetworksEditFieldLabel  matlab.ui.control.Label
        NumberofsubnetworksEditField   matlab.ui.control.NumericEditField
        ExportButton                   matlab.ui.control.Button
        SubnetworkSizesEditField_3Label  matlab.ui.control.Label
        SubnetworkSizesEditField_3     matlab.ui.control.EditField
        SubnetworkpvaluesEditFieldLabel  matlab.ui.control.Label
        SubnetworkpvaluesEditField     matlab.ui.control.EditField
        ExportFigureButton             matlab.ui.control.Button
        ExportHistogramButton          matlab.ui.control.Button
        StatisticalTestPanel           matlab.ui.container.Panel
        alphaEditField_2Label          matlab.ui.control.Label
        alphaEditField_2               matlab.ui.control.NumericEditField
        AlgorithmDropDown_2Label       matlab.ui.control.Label
        AlgorithmDropDown_2            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Wp = []; % Description
        Clist_dense = [];
        CID_dense = [];
        p_vec = [];
        W_dense = [];
        TableWp = [];
        TableClist_dense = [];
        TableW_dense = [];
        Tablep_vec = [];
        TableCID_dense = [];
        subnet_pos = [];

    end
    methods (Access = private)

        % Create UIFigure and components
        
        function modify_app(app)
             % Create Observed_Matrix
             app.Observed_Matrix.XTickLabel = [];
             app.Observed_Matrix.YTickLabel = [];
             xlabel(app.Observed_Matrix, 'Brain Region')
             ylabel(app.Observed_Matrix, 'Brain Region')
             title(app.Observed_Matrix, '')
             
             
             % create Reordered Matrix
             app.Reordered_Matrix.XTickLabel = [];
             app.Reordered_Matrix.YTickLabel = [];
             xlabel(app.Reordered_Matrix, 'Brain Region')
             ylabel(app.Reordered_Matrix, 'Brain Region')
             title(app.Reordered_Matrix, '')
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumberofsubnetworksEditField.Editable = 'off';
            app.SubnetworkSizesEditField_3.Editable = 'off';
            modify_app(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path, indx] = uigetfile({'*.mat'; '*.txt'; '*.csv'}, 'Select a file');
            filename = [path file];
            app.Wp = importdata(filename);
%             app.Observed_Matrix = Wp;
            imagesc(app.Observed_Matrix, app.Wp);colorbar(app.Observed_Matrix);colormap(app.Observed_Matrix,"jet")
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            fig = uifigure;
            d= uiprogressdlg(fig,...
                'Message','1','Cancelable','on','Indeterminate','on');
            drawnow
            
            % extract subnetworks
            if strcmp(app.AlgorithmDropDown_2.Value,'greedy')
                method = 'greedy';
                d.Message = sprintf('Searching for optimized parameters');
                [lambda_out, cut_out] = param_tuning(app.Wp, method);
                d.Message = sprintf('Extracting subnetworks');
                [app.W_dense, app.Clist_dense, app.CID_dense] = greedy_final(app.Wp, cut_out, lambda_out);
%             elseif strcmp(app.AlgorithmDropDown_2.Value, 'spectral')
%                 method = 'SICERS';
%                 d.Message = sprintf('Searching for optimized parameters');
%                 [lambda_out, cut_out] = param_tuning(app.Wp, method);
%                 d.Message = sprintf('Extracting subnetworks');
%                 [CID_dense,W_dense, Clist_dense]=SICERS_final(app.Wp,cut_out,lambda_out, 20);
            end
            
%             d.Message = sprintf('Perform permutation test');
            % Calculate test statistics for each subnetwork
            T0 = test_statistics(app.W_dense, app.CID_dense, cut_out);
            app.p_vec = T0;
%             % perform permutation test
%             W_vec = squareform(app.Wp);
%             M_perm = app.MEditField_2.Value;
%             
%             T_vec = zeros(M_perm,1);
%             for i = 1:M_perm
%                 % shuffle matrix Wp
%                 shuffled_order = randperm(length(W_vec));
%                 W_shuffled = squareform(W_vec(shuffled_order));
%                 % extract subnetworks
%                 [W_dense_temp, ~, CID_dense_temp] = greedy_final(W_shuffled, cut_out, lambda_out);
%                 T_temp = test_statistics(W_dense_temp, CID_dense_temp, cut_out);
%                 T_vec(i) = min(T_temp);
%             end
            
%             p_vec = zeros(length(CID_dense),1);
%             for i = 1:length(CID_dense)
%                 p_vec(i) = sum(T0(i) > T_vec)/M_perm;
%             end
%             
            [subnet_idx] = app.p_vec < app.alphaEditField_2.Value;
            app.NumberofsubnetworksEditField.Value = sum(subnet_idx);
            [app.subnet_pos,~] = find(subnet_idx);
            
            % display
            % Initialize an empty string
            text = '[';
            text_pval = '[';
            % Iterate through the vector elements
            for i = 1:length(app.CID_dense(app.subnet_pos))
                % Append the current element to the text
                text = [text, num2str(app.CID_dense(app.subnet_pos(i)))];
                str_pval = sprintf('%.1e', app.p_vec(i));
                text_pval = [text_pval, str_pval];
                % Add a comma and space if it's not the last element
                if i < length(app.CID_dense(app.subnet_pos))
                    text = [text, ', '];
                    text_pval = [text_pval, ','];
                end
            end
            
            % Close the square bracket
            text = [text, ']'];
            text_pval = [text_pval, ']'];
            
            app.SubnetworkSizesEditField_3.Value = num2str(text);
            app.SubnetworkpvaluesEditField.Value = num2str(text_pval);
            
            
            % Close the dialog box
            close(d)
            close(fig)
            
            imagesc(app.Reordered_Matrix, app.W_dense);colorbar(app.Reordered_Matrix);colormap(app.Reordered_Matrix, "jet")
            
            hold(app.Reordered_Matrix, "on")
            
            for i = 1:sum(app.subnet_pos)
                if i == 1
                    starting_position = [1 1];
                else
                    starting_position = [1+sum(app.CID_dense(1:app.subnet_pos(i)-1)) 1+sum(app.CID_dense(1:app.subnet_pos(i)-1))];
                end
                sz = [app.CID_dense(app.subnet_pos(i)) app.CID_dense(app.subnet_pos(i))];
                rectangle(app.Reordered_Matrix, 'Position', [starting_position, sz], 'EdgeColor', 'r', 'LineWidth', 3);
                
            end
            hold(app.Reordered_Matrix, "off")

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Convert vectors and matrix to tables
            app.TableWp = array2table(app.Wp);
            app.TableClist_dense = array2table(app.Clist_dense);
            app.TableW_dense = array2table(app.W_dense);
            app.Tablep_vec = array2table(app.p_vec);
            app.TableCID_dense = array2table(app.CID_dense);
            result.Wp = app.Wp;
            result.Clist_dense = app.Clist_dense;
            result.W_dense = app.W_dense;
            result.p_vec = app.p_vec;
            result.CID_dense = app.CID_dense;
            % Choose the file location and name with option for Excel or .mat format
            [filename, pathname, filterindex] = uiputfile({'*.xlsx', 'Excel File'; '*.mat', 'MAT-file'}, 'Save Data');
        
            % Check if the user didn't cancel the file dialog
            if isequal(filename, 0) || isequal(pathname, 0)
               disp('User selected Cancel')
               return;
            end
        
            fullpath = fullfile(pathname, filename);
        
            % Export data based on selected format
            switch filterindex
                case 1 % Excel
                    % Write each table to a different sheet in the Excel file
                    writetable(app.TableWp, fullpath, 'Sheet', 'Wp');
                    writetable(app.TableW_dense, fullpath, 'Sheet', 'W_dense');
                    writetable(app.TableClist_dense, fullpath, 'Sheet', 'Clist');
                    writetable(app.TableCID_dense, fullpath, 'Sheet', 'CID');
                    writetable(app.Tablep_vec, fullpath, 'Sheet', 'p values');
                case 2 % MAT-file
                    % Save all the data tables in a single MAT file
                    save(fullpath, 'result');
            end
        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            % Choose the location and format for saving the figure
            [filename, pathname, filterindex] = uiputfile({...
                '*.fig', 'MATLAB Figure (*.fig)'; ...
                '*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'; ...
                '*.tif', 'TIFF Image (*.tif)'; ...
                '*.pdf', 'PDF (*.pdf)'}, ...
                'Save Figure As');
        
            % Check if the user didn't cancel the file dialog
            if isequal(filename, 0) || isequal(pathname, 0)
               disp('User selected Cancel')
               return;
            end
        
            fullpath = fullfile(pathname, filename);
        
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'on';
            imagesc(app.W_dense);colorbar;colormap("jet")
            hold(app.Reordered_Matrix, "on")
            
            for i = 1:sum(app.subnet_pos)
                if i == 1
                    starting_position = [1 1];
                else
                    starting_position = [1+sum(app.CID_dense(1:app.subnet_pos(i)-1)) 1+sum(app.CID_dense(1:app.subnet_pos(i)-1))];
                end
                sz = [app.CID_dense(app.subnet_pos(i)) app.CID_dense(app.subnet_pos(i))];
                rectangle(app.Reordered_Matrix, 'Position', [starting_position, sz], 'EdgeColor', 'r', 'LineWidth', 3);
                
            end
            hold off
            % Save as png and fig files.
            saveas(fig, fullpath);
            delete(fig);
        end

        % Button pushed function: ExportHistogramButton
        function ExportHistogramButtonPushed(app, event)
            % Choose the location and format for saving the figure
            [filename, pathname, filterindex] = uiputfile({...
                '*.fig', 'MATLAB Figure (*.fig)'; ...
                '*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'; ...
                '*.tif', 'TIFF Image (*.tif)'; ...
                '*.pdf', 'PDF (*.pdf)'}, ...
                'Save Figure As');
        
            % Check if the user didn't cancel the file dialog
            if isequal(filename, 0) || isequal(pathname, 0)
               disp('User selected Cancel')
               return;
            end
        
            fullpath = fullfile(pathname, filename);
            nodeLen = size(app.Wp,1);
            edgeLen = floor(nodeLen*(nodeLen-1)/2);
            POI_edges_idx = [];
            for i = 1:length(app.subnet_pos)
                if i == 1
                    nodes_temp = app.Clist_dense(1:app.CID_dense(1));
                else
                    nodes_temp = app.Clist_dense(1+sum(app.CID_dense(1:i-1)): sum(app.CID_dense(1:i)));
                end
                POI_edges_idx = [POI_edges_idx node2vec(nodes_temp,nodes_temp, nodeLen)];
            end
            
            non_POI_edges_idx = setdiff(1:edgeLen, POI_edges_idx);
            Wp_vec = squareform(app.Wp);
            POI_edges = Wp_vec(POI_edges_idx);
            non_POI_edges = Wp_vec(non_POI_edges_idx);
            
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'on';
            
            % Plot normalized histogram for POI_edges
            hist1 = histogram(POI_edges, 20, 'Normalization', 'pdf', 'DisplayName', 'Covariate-related');
            hold on
            
            % Overlay the normal density fit for POI_edges
            pd1 = fitdist(reshape(POI_edges,length(POI_edges),1), 'Normal');
            x_values1 = linspace(min(POI_edges), max(POI_edges), 100);
            y_values1 = pdf(pd1, x_values1);
            plot(x_values1, y_values1, 'LineWidth', 2, 'HandleVisibility', 'off');
            
            % Plot normalized histogram for non_POI_edges
            hist2 = histogram(non_POI_edges, 20, 'Normalization', 'pdf', 'FaceAlpha', 0.5, 'DisplayName', 'Non-related');
            
            % Overlay the normal density fit for non_POI_edges
            pd2 = fitdist(reshape(non_POI_edges,length(non_POI_edges),1), 'Normal');
            x_values2 = linspace(min(non_POI_edges), max(non_POI_edges), 100);
            y_values2 = pdf(pd2, x_values2);
            plot(x_values2, y_values2, 'LineWidth', 2, 'HandleVisibility', 'off');
            
            % Legends
            legend('FontSize', 20);
            
            hold off
            
            % Labels
            xlabel('-log_{10}(p)', 'FontSize', 24);
            ylabel('PDF', 'FontSize', 24);
            
            % Figure position
            set(fig, 'Position', [1000 918 1205 420]);
            % Save as png and fig files.
            saveas(fig, fullpath);
            delete(fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportAdjacencymatrixTab = uitab(app.TabGroup);
            app.ImportAdjacencymatrixTab.Title = 'Import Adjacency matrix';
            app.FromexistingadjacencymatrixmattxtcsvPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.FromexistingadjacencymatrixmattxtcsvPanel.Title = 'From existing adjacency matrix (.mat, .txt, .csv)';
            app.ImportButton = uibutton(app.FromexistingadjacencymatrixmattxtcsvPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ObservedAdjacencyMatrixPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.ObservedAdjacencyMatrixPanel.Title = 'Observed Adjacency Matrix';
            app.Observed_Matrix = uiaxes(app.ObservedAdjacencyMatrixPanel);
            title(app.Observed_Matrix, 'Title')
            xlabel(app.Observed_Matrix, 'X')
            ylabel(app.Observed_Matrix, 'Y')
            app.Observed_Matrix.Visible = 'off';
            app.ReorderedAdjacencyMatrixPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.ReorderedAdjacencyMatrixPanel.Title = 'Reordered Adjacency Matrix';
            app.Reordered_Matrix = uiaxes(app.ReorderedAdjacencyMatrixPanel);
            title(app.Reordered_Matrix, 'Title')
            xlabel(app.Reordered_Matrix, 'X')
            ylabel(app.Reordered_Matrix, 'Y')
            app.Reordered_Matrix.Visible = 'off';
            app.RunButton = uibutton(app.ImportAdjacencymatrixTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultsPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.ResultsPanel.Title = 'Results';
            app.NumberofsubnetworksEditFieldLabel = uilabel(app.ResultsPanel);
            app.NumberofsubnetworksEditFieldLabel.Text = 'Number of subnetworks';
            app.NumberofsubnetworksEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.ExportButton = uibutton(app.ResultsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SubnetworkSizesEditField_3Label = uilabel(app.ResultsPanel);
            app.SubnetworkSizesEditField_3Label.Text = 'Subnetwork Sizes';
            app.SubnetworkSizesEditField_3 = uieditfield(app.ResultsPanel, 'text');
            app.SubnetworkpvaluesEditFieldLabel = uilabel(app.ResultsPanel);
            app.SubnetworkpvaluesEditFieldLabel.Text = 'Subnetwork p values';
            app.SubnetworkpvaluesEditField = uieditfield(app.ResultsPanel, 'text');
            app.SubnetworkpvaluesEditField.Editable = 'off';
            app.ExportFigureButton = uibutton(app.ResultsPanel, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.ExportHistogramButton = uibutton(app.ResultsPanel, 'push');
            app.ExportHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ExportHistogramButtonPushed, true);
            app.ExportHistogramButton.Text = 'Export Histogram';
            app.StatisticalTestPanel = uipanel(app.ImportAdjacencymatrixTab);
            app.StatisticalTestPanel.Title = 'Statistical Test';
            app.alphaEditField_2Label = uilabel(app.StatisticalTestPanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.alphaEditField_2 = uieditfield(app.StatisticalTestPanel, 'numeric');
            app.alphaEditField_2.Value = 0.05;
            app.AlgorithmDropDown_2Label = uilabel(app.StatisticalTestPanel);
            app.AlgorithmDropDown_2Label.Text = 'Algorithm';
            app.AlgorithmDropDown_2 = uidropdown(app.StatisticalTestPanel);
            app.AlgorithmDropDown_2.Items = {'greedy'};
            app.AlgorithmDropDown_2.Value = 'greedy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end